classdef tracker_CK_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1              matlab.ui.Figure
        FILE                 matlab.ui.container.Menu
        READ                 matlab.ui.container.Menu
        SAVE                 matlab.ui.container.Menu
        CHANGE               matlab.ui.container.Menu
        EXIT                 matlab.ui.container.Menu
        M_Con                matlab.ui.container.Menu
        MI_ExpFolder         matlab.ui.container.Menu
        MI_StimSettings      matlab.ui.container.Menu
        MI_ParSettings       matlab.ui.container.Menu
        MI_MonkeyName        matlab.ui.container.Menu
        view                 matlab.ui.container.Menu
        darkmode             matlab.ui.container.Menu
        lightmode            matlab.ui.container.Menu
        ParmPanel            matlab.ui.container.Panel
        DrummingonoffButton  matlab.ui.control.StateButton
        squareButton         matlab.ui.control.StateButton
        text15_3             matlab.ui.control.Label
        text15_2             matlab.ui.control.Label
        AutoDimPerc          matlab.ui.control.EditField
        AutoDim              matlab.ui.control.CheckBox
        SecFixCol            matlab.ui.control.CheckBox
        FixHIB               matlab.ui.control.CheckBox
        StimHIB              matlab.ui.control.CheckBox
        RewHIB               matlab.ui.control.CheckBox
        T_RUN                matlab.ui.control.Label
        e_RndTarg            matlab.ui.control.EditField
        text37               matlab.ui.control.Label
        E_RGB                matlab.ui.control.EditField
        text32               matlab.ui.control.Label
        text31               matlab.ui.control.Label
        TB_y                 matlab.ui.control.StateButton
        TB_x                 matlab.ui.control.StateButton
        e_Sacc               matlab.ui.control.EditField
        text28               matlab.ui.control.Label
        e_Err                matlab.ui.control.EditField
        text27               matlab.ui.control.Label
        e_RndStim            matlab.ui.control.EditField
        text26               matlab.ui.control.Label
        e_RndFix             matlab.ui.control.EditField
        text25               matlab.ui.control.Label
        text24               matlab.ui.control.Label
        text23               matlab.ui.control.Label
        LTargHt              matlab.ui.control.EditField
        LFixHt               matlab.ui.control.EditField
        ReactionT            matlab.ui.control.EditField
        text21               matlab.ui.control.Label
        PixDeg               matlab.ui.control.Label
        ScrWid               matlab.ui.control.EditField
        ScrDist              matlab.ui.control.EditField
        text20               matlab.ui.control.Label
        text19               matlab.ui.control.Label
        text18               matlab.ui.control.Label
        LTargWd              matlab.ui.control.EditField
        LFixWd               matlab.ui.control.EditField
        text15               matlab.ui.control.Label
        text14               matlab.ui.control.Label
        InterTT              matlab.ui.control.EditField
        text9                matlab.ui.control.Label
        TargT                matlab.ui.control.EditField
        text8                matlab.ui.control.Label
        StimT                matlab.ui.control.EditField
        text7                matlab.ui.control.Label
        FixT                 matlab.ui.control.EditField
        text6                matlab.ui.control.Label
        ToFixTime            matlab.ui.control.EditField
        text5                matlab.ui.control.Label
        text4                matlab.ui.control.Label
        ParmClose            matlab.ui.control.Button
        axes_timing          matlab.ui.control.UIAxes
        uipanel1             matlab.ui.container.ButtonGroup
        text10_2             matlab.ui.control.Label
        uipanel7             matlab.ui.container.Panel
        OnOFF_noise          matlab.ui.control.Button
        T_Sd                 matlab.ui.control.Label
        Mouse                matlab.ui.control.CheckBox
        T_Trl                matlab.ui.control.Label
        PB_Test              matlab.ui.control.Button
        lblScy               matlab.ui.control.Label
        lblScx               matlab.ui.control.Label
        text10               matlab.ui.control.Label
        ZoomDown             matlab.ui.control.Button
        ZoomUp               matlab.ui.control.Button
        text1                matlab.ui.control.Label
        Run                  matlab.ui.control.Button
        Stop                 matlab.ui.control.Button
        uipanel6             matlab.ui.container.Panel
        setRewField          matlab.ui.control.NumericEditField
        setEditFieldLabel    matlab.ui.control.Label
        onoffButton          matlab.ui.control.StateButton
        Lbl_Rwtime           matlab.ui.control.Label
        slider1              matlab.ui.control.Slider
        uipanel5             matlab.ui.container.ButtonGroup
        Switch               matlab.ui.control.Switch
        axes1                matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function Init(app, hObject, handles)
            % hObject    handle to Init (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % prestim %initial values, startup of das, startup of cogent
            prestim_ptb %initial values, startup of das, startup of ptb
            if isfield(Par,'DasOn') && Par.DasOn %defined in prestim
                handles.DasOn = true;
            end
            
            % initialize paramaterfields
            %timing
            set(handles.ToFixTime, 'String', num2str(Par.Times.ToFix, 4))
            set(handles.FixT, 'String', num2str(Par.Times.Fix, 4))
            set(handles.StimT, 'String', num2str(Par.Times.Stim, 4))
            
            if numel(Par.Times.Targ)==1
                set(handles.TargT, 'String', num2str(Par.Times.Targ, 4))
            else
                set(handles.TargT, 'String', num2str(Par.Times.Targ(1,2), 4))
            end
            
            set(handles.ReactionT, 'String', num2str(Par.Times.Rt, 4))
            set(handles.InterTT, 'String', num2str(Par.Times.InterTrial, 4))
            
            set(handles.e_RndFix, 'String', num2str(Par.Times.RndFix, 4))
            set(handles.e_RndStim, 'String', num2str(Par.Times.RndStim, 4))
            set(handles.e_RndTarg, 'String', num2str(Par.Times.RndTarg, 4))
            set(handles.e_Sacc, 'String', num2str(Par.Times.Sacc, 4))
            set(handles.e_Err, 'String', num2str(Par.Times.Err, 4))
            
            if numel(Par.RewardTime)==1
                set(handles.Lbl_Rwtime, 'String', num2str(Par.RewardTime, 5))
                set(handles.slider1, 'Value', Par.RewardTime)
                set(app.setRewField, 'Value', Par.RewardTime)
            else
                set(handles.Lbl_Rwtime, 'String', num2str(Par.RewardTime(1,2), 5))
                set(handles.slider1, 'Value', Par.RewardTime(1,2))
                set(app.setRewField, 'Value', Par.RewardTime(1,2))
            end
            
            %set(handles.rb_Drum, 'Value', Par.Drum)
            app.DrummingonoffButton.Value = Par.Drum;
            set(handles.E_RGB, 'String',  [ num2str(Par.BG(1)) ' ' num2str(Par.BG(2)) ' ' num2str(Par.BG(2))])
            
            Updatetimeaxes(app, handles)
            %scale eyeinput to pixelsRewarEdi
            set(handles.lblScx, 'String', num2str(Par.SCx, 4))
            set(handles.lblScy, 'String', num2str(Par.SCy, 4))
            set(handles.TB_x, 'String', ['x*' num2str(Par.xdir, 1)])
            set(handles.TB_y, 'String', ['y*' num2str(Par.ydir, 1)])
            
            %control window dimensions
            set(handles.LFixWd, 'String', num2str(Par.FixWdDeg) )
            set(handles.LTargWd, 'String', num2str(Par.TargWdDeg) )
            set(handles.LFixHt, 'String', num2str(Par.FixHtDeg) )
            set(handles.LTargHt, 'String', num2str(Par.TargHtDeg) )
            
            set(handles.ScrDist, 'String', num2str(Par.DistanceToScreen));
            set(handles.ScrWid, 'String', num2str(Par.ScreenWidthD2 * 2));
            set(handles.PixDeg, 'String', num2str(Par.PixPerDeg, 4));
            
            % some hand control and fix parameters
            set(handles.RewHIB,'Value',Par.RewNeeds.HandIsIn);
            set(handles.StimHIB,'Value',Par.StimNeeds.HandIsIn);
            set(handles.FixHIB,'Value',Par.FixNeeds.HandIsIn);
            set(handles.SecFixCol,'Value',Par.RewardFixFeedBack);
            set(handles.AutoDim,'Value',Par.HandOutDimsScreen);
            set(handles.AutoDimPerc,'Value',Par.HandOutDimsScreen_perc);
            
            
            handles.RUNFUNC = @runstim;
            set(handles.T_RUN, 'String', ['Runfunction : ' Par.RUNFUNC]);
            
            % Update handles structure
            guidata(hObject, handles);
        end
        
        function LFixx_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to LFixx (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function LFixy_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to LFixy (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function LTargx_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to LTargx (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function LTargy_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to LTargy (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function LblScy_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to LblScy (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function MI_MonkeName_Callback(app, hObject, eventdata, handles)
            % hObject    handle to MI_MonkeyName (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global Par
            FSTR = uigetfile('*.m');
            StrPth = which(FSTR);
            if strcmp(StrPth, '')
                errordlg('This function is not in the matlab path')
            else
                button =  questdlg({StrPth, '',  'Is this the function you want to use.?'},'Confirm');
                if  strcmp(button, 'Yes')
                    Str = regexp(FSTR, '\w*', 'match');
                    Par.RUNFUNC = Str{1};
                    handles.RUNFUNC = str2func(Par.RUNFUNC);
                    % Update handles structure
                    guidata(hObject, handles);
                    set(handles.T_RUN, 'String', Par.RUNFUNC);
                end
            end
        end
        
        function MI_Stim_Callback(app, hObject, eventdata, handles)
            % hObject    handle to MI_Stim (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            SG = Stimgui;
            PS = get(SG, 'Position');
            FPos = get(handles.figure1, 'Position');
            % SPos =  get(handles.figure1, 'Position');
            PS = [FPos(1)+10  PS(2:4)];
            set(SG, 'Position', PS);
        end
        
        function SAVEAS_Callback(app, hObject, eventdata, handles)
            % hObject    handle to SAVEAS (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global Par
            
            
            [file,path] = uiputfile('PAR.mat','Save PAR as');
            if file ~= 0
                save([path file], '-struct', 'Par')
            else
                errormsg('No filename supplied')
            end
        end
        
        function UPDATE_Callback(app, hObject, eventdata, handles)
            % hObject    handle to UPDATE (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            pad = which( 'Trackerupdates.html');
            web(pad)
        end
        
        function Updatetimeaxes(app, handles)
            
            global Par
            axes(handles.axes_timing);
            cla
            Times = Par.Times;
            
            plot([-(Times.ToFix + Times.Fix)  -Times.Fix], [0 0], 'Color', [0.75 0.75 0.75], 'LineWidth', 10)
            hold on
            
            if numel(Par.Times.Targ)==1
                set(handles.TargT, 'String', num2str(Par.Times.Targ, 4))
            else
                set(handles.TargT, 'String', num2str(Par.Times.Targ(1,2), 4))
            end
            
            if numel(Par.Times.Targ)==1
                plot([ -Times.Fix Times.Targ], [0 0], 'LineWidth', 10, 'Color', [0 0.75 0.75])
                plot([Times.Targ Times.Targ + Par.Times.Rt], [0 0], 'LineWidth', 10, 'Color', [0.75 0.75 0])
                plot([Times.Targ + Par.Times.Rt Times.Targ + Par.Times.Rt+Times.InterTrial], ...
                    [0 0], 'Color', [0.5 0.5 0.5], 'LineWidth', 10)
            else
                plot([ -Times.Fix Times.Targ(1,2)], [0 0], 'LineWidth', 10, 'Color', [0 0.75 0.75])
                plot([Times.Targ(1,2) Times.Targ(1,2) + Par.Times.Rt], [0 0], 'LineWidth', 10, 'Color', [0.75 0.75 0])
                plot([Times.Targ(1,2) + Par.Times.Rt Times.Targ(1,2) + Par.Times.Rt+Times.InterTrial], ...
                    [0 0], 'Color', [0.5 0.5 0.5], 'LineWidth', 10)
            end
            
            
            plot([ 0 Times.Stim], [0 0], 'k', 'LineWidth', 5)
            
            line([-Times.Fix -Times.Fix], [-0.1 1])
            text(-Times.Fix, 0.2, 'Fix')
            line([0 0], [-0.1 1])
            text(0, 0.4, 'Stim')
            
            if numel(Par.Times.Targ)==1
                line([Times.Targ Times.Targ], [-0.1 1])
                text(Times.Targ, 0.6, 'Targ')
            else
                line([Times.Targ(1,2) Times.Targ(1,2)], [-0.1 1])
                text(Times.Targ(1,2), 0.6, 'Targ')
            end
            
            % POS = get(handles.figure1, 'Position');
            lgnd1 = legend('Fix\_wait duration', 'Fixation time', 'Reaction time', 'Intertrial time', 'Stim\_on time');
            set(lgnd1,'Units','characters','Position', [5 14 30 8]);
            xlabel('time(ms)')
            set(handles.axes_timing, 'YLim', [-0.1 1])
            set(handles.axes_timing, 'YTick', [], 'YTickLabel', '')
            
            axes(handles.axes1);
        end
        
        function lblScx_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to lblScx (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function lblScy_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to lblScy (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function tracker_CK_OpeningFcn(app, varargin)
            global Par
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to tracker_CK (see VARARGIN)
            % X = varargin{1};
            % Y = varargin{2};
            % line(X, Y)
            % Choose default command line output for tracker_CK
            handles.output = hObject;
            
            Init(app, hObject, handles)

            if strcmp(Par.tracker_color, 'dark')
                %set(Par.hTracker_ax,'Color','k');
                app.axes1.Color = [0 0 0];
                recolortracker;
            end
        end

        % Value changed function: AutoDimPerc
        function AutoDimPerc_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to AutoDimPerc (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global Par
            
            % Hints: get(hObject,'String') returns contents of AutoDimPerc as text
            %        str2double(get(hObject,'String')) returns contents of AutoDimPerc as a double
            Val = str2double(get(hObject,'String'));
            Par.HandOutDimsScreen_perc = Val;
        end

        % Value changed function: AutoDim
        function AutoDim_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to AutoDim (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global Par
            % Hint: get(hObject,'Value') returns toggle state of SecFixCol
            if get(hObject,'Value') == true
                Par.HandOutDimsScreen = true;
            else
                Par.HandOutDimsScreen = false;
            end
        end

        % Menu selected function: CHANGE
        function CHANGE_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to CHANGE (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(handles.axes1, 'Visible', 'off')
            CHILD = get(handles.axes1, 'Children');
            for i = 1:length(CHILD)
                set(CHILD(i), 'Visible', 'off')
            end
            set(handles.ParmPanel, 'Visible', 'on')
            Updatetimeaxes(app, handles) %update axes showing control times
        end

        % Menu selected function: EXIT
        function EXIT_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to EXIT (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global Par
            global Control
            
            if Par.isRunning == true
            
                button = questdlg('Did your runstim crash','Alert','Yes');
                if strcmp(button, 'Yes')
                    Par.isRunning = false;
                    %poststim
                    poststim_ptb
                    delete(handles.figure1);
                end
            
            else
                poststim_ptb
                %delete(handles.figure1);
                %poststim
            end
        end

        % Value changed function: E_RGB
        function E_RGB_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to E_RGB (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of E_RGB as text
            %        str2double(get(hObject,'String')) returns contents of E_RGB as a double
            global Par
            STR = get(hObject,'String');
            strexp = '\d\.\d|\d(?!\d)';
            RGBSTR = regexp(STR, strexp, 'match');
            RGB = zeros(3,1);
            if length(RGBSTR) == 3
                for i = 1:3
                    RGB(i) = str2double(RGBSTR{i});
                    if RGB(i) > 1.0 || RGB(i) < 0.0
                        errordlg('Invalid input')
                        return
                    end
                end
                Par.BG = RGB;
            else
                errordlg('Invalid input')
            end
        end

        % Value changed function: FixHIB
        function FixHIB_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to FixHIB (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global Par
            % Hint: get(hObject,'Value') returns toggle state of FixHIB
            if get(hObject,'Value') == true
                Par.FixNeedsHandInBox = true;
            else
                Par.FixNeedsHandInBox = false;
            end
        end

        % Value changed function: FixT
        function FixT_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to FixT (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of FixT as text
            %        str2double(get(hObject,'String')) returns contents of FixT as a double
            global Par
            time = str2double(get(hObject,'String'));
            if isnan(time) || time == 0
                errordlg('Enter valid time')
            else
                Par.Times.Fix = time;
            end
            Updatetimeaxes(app, handles)
        end

        % Value changed function: InterTT
        function InterTT_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to InterTT (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of InterTT as text
            %        str2double(get(hObject,'String')) returns contents of InterTT as a double
            global Par
            time = str2double(get(hObject,'String'));
            if isnan(time) || time < 0
                errordlg('Enter valid time')
            else
                Par.Times.InterTrial = time;
            end
            Updatetimeaxes(app, handles)
        end

        % Value changed function: LFixHt
        function LFixHt_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to LFixHt (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of LFixHt as text
            %        str2double(get(hObject,'String')) returns contents of LFixHt as a double
            global Par
            Val = str2double(get(hObject,'String')); %returns contents of LFixSz as a double
            if ~isnan(Val) && Val > 0
                Par.FixHtDeg = Val;
                FixHt = Val * Par.PixPerDeg;
                for i = 1:size(Par.WIN,2)
                    if Par.WIN(5,i) == 0 %fix window == 0
                        Par.WIN(4,i) = FixHt; %width
                    end
                end
            end
        end

        % Value changed function: LFixWd
        function LFixWd_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to LFixWd (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of LFixWd as text
            global Par
            Val = str2double(get(hObject,'String')); %returns contents of LFixSz as a double
            if ~isnan(Val) && Val > 0
                Par.FixWdDeg = Val;
                FixWd = Val * Par.PixPerDeg;
                for i = 1:size(Par.WIN,2)
                    if Par.WIN(5,i) == 0 %fix window == 0
                        Par.WIN(3,i) = FixWd; %width
                    end
                end
            end
        end

        % Value changed function: LTargHt
        function LTargHt_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to LTargHt (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of LTargHt as text
            %        str2double(get(hObject,'String')) returns contents of LTargHt as a double
            
            global Par
            Val = str2double(get(hObject,'String')); %returns contents of LFixSz as a double
            if ~isnan(Val) && Val > 0
                Par.TargHtDeg = Val;
                TargHt = Val * Par.PixPerDeg;
                for i = 1:size(Par.WIN,2)
                    if Par.WIN(5,i) == 1 || Par.WIN(5,i) == 2 %target window == 2
                        Par.WIN(4,i) = TargHt; %width
                    end
                end
            end
        end

        % Value changed function: LTargWd
        function LTargWd_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to LTargWd (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of LTargWd as text
            %        str2double(get(hObject,'String')) returns contents of LTargWd as a double
            global Par
            Val = str2double(get(hObject,'String')); %returns contents of LFixSz as a double
            if ~isnan(Val) && Val > 0
                Par.TargWdDeg = Val;
                TargWd = Val * Par.PixPerDeg;
                for i = 1:size(Par.WIN,2)
                    if Par.WIN(5,i) == 1 || Par.WIN(5,i) == 2 %target window == 2
                        Par.WIN(3,i) = TargWd; %width
                    end
                end
            end
        end

        % Menu selected function: MI_ExpFolder
        function MI_ExpFolder_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to MI_ExpFolder (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global Par
            
            % go to root folder
            cd(Par.StartFolder);
            
            % remove current folders from path
            OldExpFolder = Par.ExpFolder;
            Par.ExpFolder=0;
            
            % carryover the current monkey name
            CurrMonkey = Par.MONKEY;
            
            % get new folder
            cd TRACKER_PTB
            while ~Par.ExpFolder
                Par.ExpFolder=uigetdir(pwd,...
                    'Choose your experiment root-folder (contains Engine & Experiment folders)');
            end
            
            warning off
            rmpath(genpath(OldExpFolder));
            warning on
            
            % close tracker
            close(Par.hTracker);
            % Add stuff to the path
            addpath(genpath(Par.ExpFolder));

            % Handle daspresent yes/no
            if Par.daspresent
                % remove dasdummy
                rmpath(genpath(fullfile(Par.ExpFolder,'engine','dasdummy')));
            else
                % remove das
                rmpath(genpath(fullfile(Par.ExpFolder,'engine','das64bit')));
            end

            % Go to folder
            cd(Par.ExpFolder);
            % Run tracker
            Par.hTracker = tracker_CK;
            
            %Par.hTracker_ax = findobj(Par.hTracker,'Tag','axes1');
                        
            if strcmp(Par.tracker_color, 'dark')
                %set(Par.hTracker_ax,'Color','k');
                app.axes1.Color = [0 0 0];
                recolortracker;
            end
            Par.MONKEY = CurrMonkey;
            % return focus to main Tracker window
            figure(app.figure1)
        end

        % Menu selected function: MI_MonkeyName
        function MI_MonkeyName_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to MI_ExpFolder (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global Par
            
            %get the monkeyname
            FSTR = inputdlg('Specify monkey name','Monkey Name',1,{Par.MONKEY});
            Par.MONKEY = FSTR{1};
            % return focus to main Tracker window
            figure(app.figure1)
        end

        % Menu selected function: MI_ParSettings
        function MI_ParSettings_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to MI_ExpFolder (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global Par
            
            % go to Exp folder
            cd Experiment
            cd ParSettings
            % get ParSettings file
            ParSetSel=false;
            while ~exist('FSTR') || ~ParSetSel
                FSTR = uigetfile('*.m','Choose a ParSettings file');
                if FSTR
                    ParSetSel=true;
                end
            end
            StrPth = which(FSTR);
            cd ..
            cd ..
            if strcmp(StrPth, '')
                errordlg('This file is not in the matlab path')
            else
%                 button =  questdlg({StrPth, '',  'Is this the file you want to use?'},'Confirm');
%                 if  strcmp(button, 'Yes')
%                     Str = regexp(FSTR, '\w*', 'match');
%                     Par.PARSETFILE = Str{1};
%                 end
            end
            % return focus to main Tracker window
            figure(app.figure1)
        end

        % Menu selected function: MI_StimSettings
        function MI_StimSettings_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to MI_ExpFolder (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global Par
            
            % go to Exp folder
            cd Experiment
            cd StimSettings
            % get StimSettings file
            StimSetSel=false;
            while ~exist('FSTR') || ~StimSetSel
                FSTR = uigetfile('*.m','Choose a StimSettings file');
                if FSTR
                    StimSetSel=true;
                end
            end
            StrPth = which(FSTR);
            cd ..
            cd ..
            if strcmp(StrPth, '')
                errordlg('This file is not in the matlab path')
            else
%                 button =  questdlg({StrPth, '',  'Is this the file you want to use?'},'Confirm');
%                 if  strcmp(button, 'Yes')
%                     Str = regexp(FSTR, '\w*', 'match');
%                     Par.STIMSETFILE = Str{1};
%                 end
            end
            % return focus to main Tracker window
            figure(app.figure1)
        end

        % Value changed function: Mouse
        function Mouse_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Mouse (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global Par
            val = get(hObject, 'Value');
            if(val >= 1)
                Par.Mouserun = 1;
            
                % set(hObject, 'Value', 0);
            else
                Par.Mouserun = 0;
                % calllib(Par.Dll, 'Use_Mouse', 0);
                dasusemouse( 0 );
                %  set(hObject, 'Value', 1);
            end
        end

        % Button pushed function: OnOFF_noise
        function OnOFF_noise_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to OnOFF_noise (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global Par
            
            if Par.NoiseUpdate == 1
                Par.NoiseUpdate = 0;
                dassetnoise(0)
                set(hObject, 'BackgroundColor', [0.83 0.82 0.78])
            else
                Par.NoiseUpdate = 1;
                dassetnoise(1)
                set(hObject, 'BackgroundColor', [1.0 0.95 0.87])
            end
        end

        % Button pushed function: PB_Test
        function PB_Test_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to PB_Test (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global Par
            if isfield(handles, 'DasOn') && handles.DasOn
                set(hObject, 'BackgroundColor', [0.5 0.6 0.9])
                Par.isRunning = true;
                axes( handles.axes1);
            
                Par.ESC = false; %escape has not been pressed
                Par.Updatxy = 1;
                while ~Par.ESC
                    pause(0.05)
                    DasCheck; %retrieve position values and plot on Control display
                end
                Par.Updatxy = 0;
            end
            Par.isRunning = false;
            set(hObject, 'BackgroundColor', [0.925 0.914 0.847])
        end

        % Button pushed function: ParmClose
        function ParmClose_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ParmClose (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(handles.ParmPanel, 'Visible', 'off')
            
            refreshtracker( 1)
            set(handles.axes1, 'Visible', 'on')
        end

        % Menu selected function: READ
        function READ_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to READ (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global Par
            
            RUNFUNC = '';
            
            [FileName,PathName] = uigetfile('PAR.mat','Select a parameter Mat-file','PAR.mat');
            Pf = fullfile(PathName,FileName);
            
            if exist(Pf)
                load(Pf)
                Par.Times = Times;
                Par.Drum = Drum;
            
                Par.SCx = SCx;
                Par.SCy = SCy;
            
                Par.DistanceToScreen = DistanceToScreen;
                Par.ScreenWidthD2 = ScreenWidthD2;
                Par.PixPerDeg = PixPerDeg;
                Par.FixWdDeg = FixWdDeg;
                Par.TargWdDeg = TargWdDeg;
                Par.FixHtDeg = FixHtDeg;
                Par.TargHtDeg = TargHtDeg;
                Par.xdir = xdir;
                Par.ydir = ydir;
            
                Par.BG = BG;
            
                if exist(RUNFUNC, 'var')
                    Par.RUNFUNC = RUNFUNC;
            
                    handles.RUNFUNC = str2fun(Par.RUNFUNC);
                    set(handles.T_RUN, 'String', ['Runfunction : ' Par.RUNFUNC]);
                end
            
                set(handles.ToFixTime, 'String', num2str(Times.ToFix, 4));
                set(handles.FixT, 'String', num2str(Times.Fix, 4));
                set(handles.StimT, 'String', num2str(Times.Stim, 4));
            
                %set(handles.TargT, 'String', num2str(Times.Targ, 4));
                if numel(Par.Times.Targ)==1
                    set(handles.TargT, 'String', num2str(Par.Times.Targ, 4))
                else
                    set(handles.TargT, 'String', num2str(Par.Times.Targ(1,2), 4))
                end
            
                set(handles.ReactionT, 'String', num2str(Par.Times.Rt, 4))
                set(handles.InterTT, 'String', num2str(Times.InterTrial, 4));
                Updatetimeaxes(app, handles) %update axes showing control times
            
                set(handles.e_RndFix, 'String', num2str(Par.Times.RndFix, 4))
                set(handles.e_RndStim, 'String', num2str(Par.Times.RndStim, 4))
                set(handles.e_RndTarg, 'String', num2str(Par.Times.RndTarg, 4))
                set(handles.e_Sacc, 'String', num2str(Par.Times.Sacc, 4))
                set(handles.e_Err, 'String', num2str(Par.Times.Err, 4))
            
                set(handles.rb_Drum, 'Value', Par.Drum)
                set(handles.E_RGB, 'String',  [ num2str(Par.BG(1)) ' ' num2str(Par.BG(2)) ' ' num2str(Par.BG(2))])
            
                set(handles.lblScx, 'String', num2str(Par.SCx, 4));
                set(handles.lblScy, 'String', num2str(Par.SCy, 4));
                set(handles.TB_x, 'String', ['x*' num2str(Par.xdir, 1)])
                set(handles.TB_y, 'String', ['y*' num2str(Par.ydir, 1)])
            
                %control window dimensions
                set(handles.LFixWd, 'String', num2str(Par.FixWdDeg) )
                set(handles.LTargWd, 'String', num2str(Par.TargWdDeg) )
                set(handles.LFixHt, 'String', num2str(Par.FixHtDeg) )
                set(handles.LTargHt, 'String', num2str(Par.TargHtDeg) )
            
                set(handles.ScrDist, 'String', num2str(Par.DistanceToScreen));
                set(handles.ScrWid, 'String', num2str(Par.ScreenWidthD2 * 2));
                set(handles.PixDeg, 'String', num2str(Par.PixPerDeg, 4));
            
                % some hand control and fix parameters
                set(handles.RewHIB,'Value',Par.RewNeeds.HandIsIn);
                set(handles.StimHIB,'Value',Par.StimNeeds.HandIsIn);
                set(handles.FixHIB,'Value',Par.FixNeeds.HandIsIn);
                set(handles.SecFixCol,'Value',Par.RewardFixFeedBack);
                set(handles.AutoDim,'Value',Par.HandOutDimsScreen);
                set(handles.AutoDimPerc,'Value',Par.HandOutDimsScreen_perc);
            
                %     FixSz = Par.FixSzDeg * Par.PixPerDeg;
                %     TargSz = Par.TargSzDeg * Par.PixPerDeg;
                for i = 1:size(Par.WIN,2)
                    if Par.WIN(5,i) == 0 %fix window == 0
                        Par.WIN(3,i) = Par.FixHtDeg * Par.PixPerDeg; %width
                        Par.WIN(4,i) = Par.FixWdDeg * Par.PixPerDeg; %height
            
                    elseif Par.WIN(5,i) == 1 || Par.WIN(5,i) == 2 %target window == 2
                        Par.WIN(3,i) = Par.TargHtDeg * Par.PixPerDeg; %width
                        Par.WIN(4,i) = Par.TargWdDeg * Par.PixPerDeg; %height
                    end
                end
            
                guidata(hObject, handles);
            else
                errordlg('No saved Parameters to retrieve')
            end
            % return focus to main Tracker window
            figure(app.figure1)
        end

        % Value changed function: ReactionT
        function ReactionT_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ReactionT (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ReactionT as text
            %        str2double(get(hObject,'String')) returns contents of ReactionT as a double
            global Par
            time = str2double(get(hObject,'String'));
            if isnan(time) || time <= 0
                errordlg('Enter valid time')
            else
                Par.Times.Rt = time;
            end
            Updatetimeaxes(app, handles)
        end

        % Value changed function: RewHIB
        function RewHIB_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to RewHIB (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global Par
            % Hint: get(hObject,'Value') returns toggle state of RewHIB
            if get(hObject,'Value') == true
                Par.RewNeedsHandInBox = true;
            else
                Par.RewNeedsHandInBox = false;
            end
        end

        % Button pushed function: Run
        function Run_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Run (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global Par
            if isfield(handles, 'DasOn') && handles.DasOn
                set(hObject, 'BackgroundColor', [0.5 0.6 0.9])
                Par.isRunning = true;
                axes(handles.axes1);
            
                %the runfunction is now a handle, don't change!!!!!
                handles.RUNFUNC([handles.T_Trl handles.T_Sd]) %gets a handle to update trial numbers and noise value
            
            end  %IS_RUNNING%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            Par.isRunning = false;
            set(hObject, 'BackgroundColor', [0.925 0.914 0.847])
        end

        % Menu selected function: SAVE
        function SAVE_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to SAVE (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global Par
            
            save( 'PAR.mat', '-struct', 'Par')
        end

        % Value changed function: ScrDist
        function ScrDist_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ScrDist (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ScrDist as text
            %        str2double(get(hObject,'String')) returns contents of ScrDist as a double
            global Par
            Val = str2double(get(hObject,'String')); %returns contents as a double
            if ~isnan(Val) && Val > 0
                Par.DistanceToScreen = Val;
                Par.PixPerDeg = Par.HW/atand(Par.ScreenWidthD2/Val);
                set(handles.PixDeg, 'String', num2str(Par.PixPerDeg) )
            
            
                %         TargSz = Par.TargSzDeg * Par.PixPerDeg;
                %         FixSz = Par.FixSzDeg * Par.PixPerDeg;
                for i = 1:size(Par.WIN,2)
                    if Par.WIN(5,i) == 0 %fix window == 0
                        Par.WIN(3,i) = Par.FixWdDeg * Par.PixPerDeg; %width
                        Par.WIN(4,i) = Par.FixHtDeg * Par.PixPerDeg; %height
            
                    elseif Par.WIN(5,i) == 1 || Par.WIN(5,i) == 2 %target window == 2
                        Par.WIN(3,i) = Par.TargWdDeg * Par.PixPerDeg; %width
                        Par.WIN(4,i) = Par.TargHtDeg * Par.PixPerDeg; %heigth
                    end
                end
            
            
            else
                errordlg('Invalid  input');
            end
        end

        % Value changed function: ScrWid
        function ScrWid_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ScrWid (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ScrWid as text
            %        str2double(get(hObject,'String')) returns contents of ScrWid as a double
            global Par
            
            Val = str2double(get(hObject,'String')); %returns contents as a double
            if ~isnan(Val) && Val > 0
                Par.ScreenWidthD2 = Val/2;
                Par.PixPerDeg = Par.HW/atand(Par.ScreenWidthD2/Par.DistanceToScreen);
                set(handles.PixDeg, 'String', num2str(Par.PixPerDeg) )
            
                %         TargSz = Par.TargSzDeg * Par.PixPerDeg;
                %         FixSz = Par.FixWdDeg * Par.PixPerDeg;
                for i = 1:size(Par.WIN,2)
                    if Par.WIN(5,i) == 0 %fix window == 0
                        Par.WIN(3,i) = Par.FixWdDeg * Par.PixPerDeg; %width
                        Par.WIN(4,i) = Par.FixHtDeg * Par.PixPerDeg;%height
                    elseif Par.WIN(5,i) == 1 || Par.WIN(5,i) == 2 %target window == 2
                        Par.WIN(3,i) = Par.TargWdDeg * Par.PixPerDeg;  %width
                        Par.WIN(4,i) = Par.TargHtDeg * Par.PixPerDeg;
                    end
                end
            
            
            else
                errordlg('Invalid  input');
            end
        end

        % Value changed function: SecFixCol
        function SecFixCol_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to SecFixCol (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global Par
            % Hint: get(hObject,'Value') returns toggle state of SecFixCol
            if get(hObject,'Value') == true
                Par.RewardFixFeedBack = true;
            else
                Par.RewardFixFeedBack = false;
            end
        end

        % Value changed function: StimHIB
        function StimHIB_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to StimHIB (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global Par
            % Hint: get(hObject,'Value') returns toggle state of StimHIB
            if get(hObject,'Value') == true
                Par.StimNeedsHandInBox = true;
            else
                Par.StimNeedsHandInBox = false;
            end
        end

        % Value changed function: StimT
        function StimT_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to StimT (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of StimT as text
            %        str2double(get(hObject,'String')) returns contents of StimT as a double
            global Par
            time = str2double(get(hObject,'String'));
            if isnan(time) || time <= 0
                errordlg('Enter valid time')
            else
                Par.Times.Stim = round(round(time/Par.fliptime)*Par.fliptime);
                set(hObject,'String', num2str(Par.Times.Stim) )
            end
            Updatetimeaxes(app, handles)
        end

        % Button pushed function: Stop
        function Stop_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Stop (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global Par
            
            Par.ESC = true;
        end

        % Value changed function: TB_x
        function TB_x_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to TB_x (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of TB_x
            global Par
            if Par.xdir == 1
                Par.xdir = -1;
            else
                Par.xdir = 1;
            end
            set(hObject, 'String', ['x*' num2str(Par.xdir, 1)])
        end

        % Value changed function: TB_y
        function TB_y_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to TB_y (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of TB_y
            global Par
            if Par.ydir == 1
                Par.ydir = -1;
            else
                Par.ydir = 1;
            end
            set(hObject, 'String', ['y*' num2str(Par.ydir, 1)])
        end

        % Value changed function: TargT
        function TargT_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to TargT (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of TargT as text
            %        str2double(get(hObject,'String')) returns contents of TargT as a double
            global Par
            time = str2double(get(hObject,'String'));
            if isnan(time) || time < 0
                errordlg('Enter valid time')
            else
                Par.Times.Targ = round(round(time/Par.fliptime)*Par.fliptime);
                set(hObject,'String', num2str(Par.Times.Targ) )
            
            end
            Updatetimeaxes(app, handles)
        end

        % Value changed function: ToFixTime
        function ToFixTime_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ToFixTime (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ToFixTime as text
            %        str2double(get(hObject,'String')) returns contents of ToFixTime as a double
            global Par
            time = str2double(get(hObject,'String'));
            if isnan(time) || time == 0
                errordlg('Enter valid time')
            else
                Par.Times.ToFix = time;
            end
            Updatetimeaxes(app, handles)
        end

        % Button pushed function: ZoomDown
        function ZoomDown_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ZoomDown (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global Par
            Par.ZOOM = Par.ZOOM * 0.8;
            cla
            refreshtracker( 1)
        end

        % Button pushed function: ZoomUp
        function ZoomUp_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ZoomUp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global Par
            Par.ZOOM = Par.ZOOM * 1.25;
            cla
            refreshtracker( 1)
        end

        % Menu selected function: darkmode
        function darkmode_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to darkmode (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global Par
            %set(Par.hTracker_ax,'Color','k');
            app.axes1.Color = [0 0 0];
            Par.tracker_color = 'dark';
            recolortracker;
        end

        % Value changed function: e_Err
        function e_Err_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to e_Err (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of e_Err as text
            %        str2double(get(hObject,'String')) returns contents of e_Err as a double
            global Par
            Val = str2double(get(hObject,'String'));
            if ~isnan(Val)
                Par.Times.Err = Val;
            else
                errordlg('Invalid  input');
            end
        end

        % Value changed function: e_RndFix
        function e_RndFix_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to e_RndFix (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of e_RndFix as text
            %        str2double(get(hObject,'String')) returns contents of e_RndFix as a double
            global Par
            Val = str2double(get(hObject,'String'));
            if ~isnan(Val)
                Par.Times.RndFix = Val;
            else
                errordlg('Invalid  input');
            end
        end

        % Value changed function: e_RndStim
        function e_RndStim_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to e_RndStim (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of e_RndStim as text
            %        str2double(get(hObject,'String')) returns contents of e_RndStim as a double
            global Par
            Val = str2double(get(hObject,'String'));
            if ~isnan(Val) && Val >= 0
                Par.Times.RndStim = Val;
            else
                errordlg('Invalid  input');
            end
        end

        % Value changed function: e_RndTarg
        function e_RndTarg_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to e_RndTarg (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of e_RndTarg as text
            %        str2double(get(hObject,'String')) returns contents of e_RndTarg as a double
            global Par
            Val = str2double(get(hObject,'String'));
            if ~isnan(Val) && Val >= 0
                Par.Times.RndTarg = Val;
            else
                errordlg('Invalid  input');
            end
        end

        % Value changed function: e_Sacc
        function e_Sacc_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to e_Sacc (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of e_Sacc as text
            %        str2double(get(hObject,'String')) returns contents of e_Sacc as a double
            global Par
            Val = str2double(get(hObject,'String'));
            if ~isnan(Val)
                Par.Times.Sacc = Val;
            else
                errordlg('Invalid  input');
            end
        end

        % Key press function: figure1
        function figure1_KeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to figure1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global Par
            
            Par.KeyDetectedInTrackerWindow = true;
            KP = double(get(hObject, 'CurrentCharacter'));
            %ST = get(hObject, 'SelectionType');
            %disp(ST) %works with mouse buttons!!!!left = 'normal', middle = 'extend',
            %right = 'alt'
            % if strcmp(ST,'normal')
            %     Par.MousePress = 0;
            %     set(handles.rb_SCALE, 'Value', 1.0);
            % elseif strcmp(ST,'alt')
            %     Par.MousePress = 2;
            %     set(handles.rb_SHIFT, 'Value', 1.0);
            % end
            
            
            if ~isempty(KP)
                switch KP
                    case {28, 97} %left arrow was pressed
                        if Par.MousePress == 2
                            %Par.OFFx = Par.OFFx - 1 * Par.xdir;
                            %calllib(Par.Dll, 'ShiftOffset', -1, 0);
                            Par.ScaleOff = dasoffset( -1, 0);
                        else
                            Par.SCx = Par.SCx * 1/1.1;
                            %set(handles.lblScx, 'String', num2str(Par.SCx, 4))
                            app.lblScx.Text = num2str(Par.SCx, 4);
                            Par.ScaleOff(3) = Par.SCx;
                        end
                        %disp('keypress left')
                    case {29, 100} %Right arrow was pressed
                        if Par.MousePress == 2
                            %Par.OFFx = Par.OFFx + 1 * Par.xdir;
                            %calllib(Par.Dll, 'ShiftOffset', 1, 0);
                            Par.ScaleOff = dasoffset( 1, 0);
                        else
                            Par.SCx = Par.SCx * 1.1;
                            %set(handles.lblScx, 'String', num2str(Par.SCx, 4))
                            app.lblScx.Text = num2str(Par.SCx, 4);
                            Par.ScaleOff(3) = Par.SCx;
                        end
                        % disp('keypress right')
                    case {30, 119} %up arrow was pressed (was 30)
                        if Par.MousePress == 2
                            %Par.OFFy = Par.OFFy + 1 * Par.ydir;
                            %calllib(Par.Dll, 'ShiftOffset', 0, 1);
                            Par.ScaleOff = dasoffset( 0, 1);
                        else
                            Par.SCy = Par.SCy * 1.1;
                            %set(handles.lblScy, 'String', num2str(Par.SCy, 4))
                            app.lblScy.Text = num2str(Par.SCy, 4);
                            Par.ScaleOff(4) = Par.SCy;
                        end
                        %disp('keypress up')
                    case {31, 115} %down arrow was pressed
                        if Par.MousePress == 2
                            % Par.OFFy = Par.OFFy - 1 * Par.ydir;
                            %calllib(Par.Dll, 'ShiftOffset', 0, -1);
                            Par.ScaleOff = dasoffset( 0, -1);
                        else
                            Par.SCy = Par.SCy * 1/1.1;
                            %set(handles.lblScy, 'String', num2str(Par.SCy, 4))
                            app.lblScy.Text = num2str(Par.SCy, 4);
                            Par.ScaleOff(4) = Par.SCy;
                        end
                        % disp('keypress down')
                    case 27  %escape key was pressed
                        Par.ESC = 1;
                        % disp('keypress ESC')
                    case 122 %Z key was pressed
                        % Par.SetZero = true;
                        % disp('keypress ZERO')
                        if Par.Mouserun
                            %             LPM = calllib(Par.Dll, 'Use_Mouse', Par.Mouserun );
                            %             setdatatype(LPM, 'int32Ptr', 2, 1);
                            Par.MOff = dasusemouse( 1 );
                        else
                            %calllib(Par.Dll,'SetZero');
                            Par.ScaleOff = daszero();
                        end
                    %case 106 %j key was pressed , give juice reward
                        % %         calllib(Par.Dll, 'DO_Bit', Par.RewardB, 1);
                        % %         calllib(Par.Dll, 'Juice', 3.5);
                        %         dasbit( Par.RewardB, 1);
                        %         dasjuice( 4 );
                        %         pause(Par.RewardTime)
                        % %         calllib(Par.Dll, 'DO_Bit', Par.RewardB, 0);
                        % %         calllib(Par.Dll, 'Juice', 0.0);
                        %         dasbit( Par.RewardB, 0);
                        %         dasjuice( 0 );
                        %         disp('Reward')
                    otherwise
                        %disp(KP)
                end
            
            end
        end

        % Menu selected function: lightmode
        function lightmode_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to lightmode (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global Par
            %set(Par.hTracker_ax,'Color','w');
            app.axes1.Color = [1 1 1];
            Par.tracker_color = 'light';
            recolortracker;
        end

        % Value changed function: slider1
        function slider1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to slider1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            global Par
            Par.RewardTime = get(hObject,'Value');
            set(handles.Lbl_Rwtime, 'String', num2str(Par.RewardTime, '% -5.3f'));
            set(app.setRewField, 'Value', Par.RewardTime)
        end

        % Value changed function: squareButton
        function FixWinSqr(app, event)
            global Par
            Par.Bsqr = app.squareButton.Value;
        end

        % Value changed function: DrummingonoffButton
        function Drum(app, event)
            global Par
            Par.Drum = app.DrummingonoffButton.Value;
        end

        % Value changed function: onoffButton
        function juiceradio(app, event)
            global Par
            Par.Reward = app.onoffButton.Value;
        end

        % Value changed function: Switch
        function shiftorscale(app, event)
            global Par 
            value = app.Switch.Value;
            if strcmp(value,'scale')
                %fprintf('set scale\n')
                Par.MousePress = 0;
            elseif strcmp(value,'shift')
                Par.MousePress = 2;
                %fprintf('set shift\n')
            end
        end

        % Value changed function: setRewField
        function setRewFieldValueChanged(app, event)
            global Par
            Par.RewardTime = app.setRewField.Value;
            set(app.Lbl_Rwtime, 'Text', num2str(Par.RewardTime, '% -5.3f'));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'tracker_CK';
            app.figure1.KeyPressFcn = createCallbackFcn(app, @figure1_KeyPressFcn, true);
            app.figure1.Tag = 'figure1';
            app.FILE = uimenu(app.figure1);
            app.FILE.Accelerator = 'P';
            app.FILE.Text = 'File';
            app.FILE.Tag = 'FILE';
            app.READ = uimenu(app.FILE);
            app.READ.MenuSelectedFcn = createCallbackFcn(app, @READ_Callback, true);
            app.READ.Text = 'Read Parameters';
            app.READ.Tag = 'READ';
            app.SAVE = uimenu(app.FILE);
            app.SAVE.MenuSelectedFcn = createCallbackFcn(app, @SAVE_Callback, true);
            app.SAVE.Text = 'Save Parameters';
            app.SAVE.Tag = 'SAVE';
            app.CHANGE = uimenu(app.FILE);
            app.CHANGE.MenuSelectedFcn = createCallbackFcn(app, @CHANGE_Callback, true);
            app.CHANGE.Text = 'Change Parameters';
            app.CHANGE.Tag = 'CHANGE';
            app.EXIT = uimenu(app.FILE);
            app.EXIT.MenuSelectedFcn = createCallbackFcn(app, @EXIT_Callback, true);
            app.EXIT.Text = 'Exit';
            app.EXIT.Tag = 'EXIT';
            app.M_Con = uimenu(app.figure1);
            app.M_Con.Text = 'Context';
            app.M_Con.Tag = 'M_Con';
            app.MI_ExpFolder = uimenu(app.M_Con);
            app.MI_ExpFolder.MenuSelectedFcn = createCallbackFcn(app, @MI_ExpFolder_Callback, true);
            app.MI_ExpFolder.Text = 'New ExpFolder';
            app.MI_ExpFolder.Tag = 'MI_ExpFolder';
            app.MI_StimSettings = uimenu(app.M_Con);
            app.MI_StimSettings.MenuSelectedFcn = createCallbackFcn(app, @MI_StimSettings_Callback, true);
            app.MI_StimSettings.Text = 'New StimSettings';
            app.MI_StimSettings.Tag = 'MI_StimSettings';
            app.MI_ParSettings = uimenu(app.M_Con);
            app.MI_ParSettings.MenuSelectedFcn = createCallbackFcn(app, @MI_ParSettings_Callback, true);
            app.MI_ParSettings.Text = 'New ParSettings';
            app.MI_ParSettings.Tag = 'MI_ParSettings';
            app.MI_MonkeyName = uimenu(app.M_Con);
            app.MI_MonkeyName.MenuSelectedFcn = createCallbackFcn(app, @MI_MonkeyName_Callback, true);
            app.MI_MonkeyName.Text = 'Set Monkey Name';
            app.MI_MonkeyName.Tag = 'MI_MonkeyName';
            app.view = uimenu(app.figure1);
            app.view.Text = 'View';
            app.view.Tag = 'view';
            app.darkmode = uimenu(app.view);
            app.darkmode.MenuSelectedFcn = createCallbackFcn(app, @darkmode_Callback, true);
            app.darkmode.Text = 'Dark mode';
            app.darkmode.Tag = 'darkmode';
            app.lightmode = uimenu(app.view);
            app.lightmode.MenuSelectedFcn = createCallbackFcn(app, @lightmode_Callback, true);
            app.lightmode.Text = 'Light mode';
            app.lightmode.Tag = 'lightmode';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.XLim = [-512 512];
            app.axes1.YLim = [-384 384];
            app.axes1.CLim = [0 1];
            app.axes1.ALim = [0 1];
            app.axes1.XTick = zeros(1,0);
            app.axes1.YTick = zeros(1,0);
            app.axes1.ZTick = [0 0.5 1];
            app.axes1.ZTickLabel = '';
            app.axes1.Tag = 'axes1';
            app.uipanel1 = uibuttongroup(app.figure1);
            app.uipanel1.Title = 'CONTROL';
            app.uipanel1.Tag = 'uipanel1';
            app.uipanel5 = uibuttongroup(app.uipanel1);
            app.uipanel5.Title = 'EYE CHANNELS';
            app.uipanel5.Tag = 'uipanel5';
            app.Switch = uiswitch(app.uipanel5, 'slider');
            app.Switch.Items = {'shift', 'scale'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @shiftorscale, true);
            app.Switch.Value = 'shift';
            app.uipanel6 = uipanel(app.uipanel1);
            app.uipanel6.Title = 'REWARD (s)';
            app.uipanel6.Tag = 'uipanel6';
            app.slider1 = uislider(app.uipanel6);
            app.slider1.Limits = [0 1];
            app.slider1.MajorTicks = [];
            app.slider1.ValueChangedFcn = createCallbackFcn(app, @slider1_Callback, true);
            app.slider1.MinorTicks = [];
            app.slider1.Tag = 'slider1';
            app.slider1.Value = 0.1;
            app.Lbl_Rwtime = uilabel(app.uipanel6);
            app.Lbl_Rwtime.Tag = 'Lbl_Rwtime';
            app.Lbl_Rwtime.Text = {'0.005'; ''};
            app.onoffButton = uibutton(app.uipanel6, 'state');
            app.onoffButton.ValueChangedFcn = createCallbackFcn(app, @juiceradio, true);
            app.onoffButton.Text = 'on/off';
            app.setEditFieldLabel = uilabel(app.uipanel6);
            app.setEditFieldLabel.Text = 'set';
            app.setRewField = uieditfield(app.uipanel6, 'numeric');
            app.setRewField.ValueDisplayFormat = '%.3f';
            app.setRewField.ValueChangedFcn = createCallbackFcn(app, @setRewFieldValueChanged, true);
            app.setRewField.Tooltip = {'set reward time'};
            app.Stop = uibutton(app.uipanel1, 'push');
            app.Stop.ButtonPushedFcn = createCallbackFcn(app, @Stop_Callback, true);
            app.Stop.Tag = 'Stop';
            app.Stop.Text = 'Stop';
            app.Run = uibutton(app.uipanel1, 'push');
            app.Run.ButtonPushedFcn = createCallbackFcn(app, @Run_Callback, true);
            app.Run.Tag = 'Run';
            app.Run.Text = 'Run';
            app.text1 = uilabel(app.uipanel1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'ZOOM';
            app.ZoomUp = uibutton(app.uipanel1, 'push');
            app.ZoomUp.ButtonPushedFcn = createCallbackFcn(app, @ZoomUp_Callback, true);
            app.ZoomUp.Tag = 'ZoomUp';
            app.ZoomUp.Text = 'In';
            app.ZoomDown = uibutton(app.uipanel1, 'push');
            app.ZoomDown.ButtonPushedFcn = createCallbackFcn(app, @ZoomDown_Callback, true);
            app.ZoomDown.Tag = 'ZoomDown';
            app.ZoomDown.Text = 'Out';
            app.text10 = uilabel(app.uipanel1);
            app.text10.Tag = 'text10';
            app.text10.Text = 'x gain';
            app.lblScx = uilabel(app.uipanel1);
            app.lblScx.Tag = 'lblScx';
            app.lblScx.Text = '0';
            app.lblScy = uilabel(app.uipanel1);
            app.lblScy.Tag = 'lblScy';
            app.lblScy.Text = '0';
            app.PB_Test = uibutton(app.uipanel1, 'push');
            app.PB_Test.ButtonPushedFcn = createCallbackFcn(app, @PB_Test_Callback, true);
            app.PB_Test.Tag = 'PB_Test';
            app.PB_Test.Text = 'Test Eye';
            app.T_Trl = uilabel(app.uipanel1);
            app.T_Trl.Tag = 'T_Trl';
            app.T_Trl.Text = {'TRIALS'; 'T: 0  '; 'C: 0'; 'M: 0'; 'F: 0'};
            app.Mouse = uicheckbox(app.uipanel1);
            app.Mouse.ValueChangedFcn = createCallbackFcn(app, @Mouse_Callback, true);
            app.Mouse.Tag = 'Mouse';
            app.Mouse.Text = 'Mouse';
            app.uipanel7 = uipanel(app.uipanel1);
            app.uipanel7.Title = 'NOISE 2sd Vdeg';
            app.uipanel7.Tag = 'uipanel7';
            app.T_Sd = uilabel(app.uipanel7);
            app.T_Sd.Tag = 'T_Sd';
            app.T_Sd.Text = {'0'; '0'};
            app.OnOFF_noise = uibutton(app.uipanel7, 'push');
            app.OnOFF_noise.ButtonPushedFcn = createCallbackFcn(app, @OnOFF_noise_Callback, true);
            app.OnOFF_noise.Tag = 'OnOFF_noise';
            app.OnOFF_noise.Text = 'On/Off';
            app.text10_2 = uilabel(app.uipanel1);
            app.text10_2.Tag = 'text10';
            app.text10_2.Text = 'y gain';
            app.ParmPanel = uipanel(app.figure1);
            app.ParmPanel.Title = 'PARAMETERS';
            app.ParmPanel.Visible = 'off';
            app.ParmPanel.Tag = 'ParmPanel';
            app.axes_timing = uiaxes(app.ParmPanel);
            app.axes_timing.YTick = zeros(1,0);
            app.axes_timing.YTickLabel = {''; ''; ''};
            app.axes_timing.Tag = 'axes_timing';
            app.ParmClose = uibutton(app.ParmPanel, 'push');
            app.ParmClose.ButtonPushedFcn = createCallbackFcn(app, @ParmClose_Callback, true);
            app.ParmClose.Tag = 'ParmClose';
            app.ParmClose.Text = 'CLOSE';
            app.text4 = uilabel(app.ParmPanel);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Timing (ms)';
            app.text5 = uilabel(app.ParmPanel);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Time to fix';
            app.ToFixTime = uieditfield(app.ParmPanel, 'text');
            app.ToFixTime.ValueChangedFcn = createCallbackFcn(app, @ToFixTime_Callback, true);
            app.ToFixTime.Tag = 'ToFixTime';
            app.ToFixTime.Value = '0';
            app.text6 = uilabel(app.ParmPanel);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Fix hold';
            app.FixT = uieditfield(app.ParmPanel, 'text');
            app.FixT.ValueChangedFcn = createCallbackFcn(app, @FixT_Callback, true);
            app.FixT.Tag = 'FixT';
            app.FixT.Value = '0';
            app.text7 = uilabel(app.ParmPanel);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Stim on time';
            app.StimT = uieditfield(app.ParmPanel, 'text');
            app.StimT.ValueChangedFcn = createCallbackFcn(app, @StimT_Callback, true);
            app.StimT.Tag = 'StimT';
            app.StimT.Value = '0';
            app.text8 = uilabel(app.ParmPanel);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Target time';
            app.TargT = uieditfield(app.ParmPanel, 'text');
            app.TargT.ValueChangedFcn = createCallbackFcn(app, @TargT_Callback, true);
            app.TargT.Tag = 'TargT';
            app.TargT.Value = '0';
            app.text9 = uilabel(app.ParmPanel);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Intertrial';
            app.InterTT = uieditfield(app.ParmPanel, 'text');
            app.InterTT.ValueChangedFcn = createCallbackFcn(app, @InterTT_Callback, true);
            app.InterTT.Tag = 'InterTT';
            app.InterTT.Value = '0';
            app.text14 = uilabel(app.ParmPanel);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Control window dimensions (deg)';
            app.text15 = uilabel(app.ParmPanel);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Scale (pix/deg)';
            app.LFixWd = uieditfield(app.ParmPanel, 'text');
            app.LFixWd.ValueChangedFcn = createCallbackFcn(app, @LFixWd_Callback, true);
            app.LFixWd.Tag = 'LFixWd';
            app.LFixWd.Value = '0';
            app.LTargWd = uieditfield(app.ParmPanel, 'text');
            app.LTargWd.ValueChangedFcn = createCallbackFcn(app, @LTargWd_Callback, true);
            app.LTargWd.Tag = 'LTargWd';
            app.LTargWd.Value = '0';
            app.text18 = uilabel(app.ParmPanel);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Visual scaling';
            app.text19 = uilabel(app.ParmPanel);
            app.text19.Tag = 'text19';
            app.text19.Text = 'Distance to screen (cm)';
            app.text20 = uilabel(app.ParmPanel);
            app.text20.Tag = 'text20';
            app.text20.Text = 'Screen width (cm)';
            app.ScrDist = uieditfield(app.ParmPanel, 'text');
            app.ScrDist.ValueChangedFcn = createCallbackFcn(app, @ScrDist_Callback, true);
            app.ScrDist.Tag = 'ScrDist';
            app.ScrDist.Value = '0';
            app.ScrWid = uieditfield(app.ParmPanel, 'text');
            app.ScrWid.ValueChangedFcn = createCallbackFcn(app, @ScrWid_Callback, true);
            app.ScrWid.Tag = 'ScrWid';
            app.ScrWid.Value = '0';
            app.PixDeg = uilabel(app.ParmPanel);
            app.PixDeg.Tag = 'PixDeg';
            app.PixDeg.Text = '0';
            app.text21 = uilabel(app.ParmPanel);
            app.text21.Tag = 'text21';
            app.text21.Text = 'Reaction time';
            app.ReactionT = uieditfield(app.ParmPanel, 'text');
            app.ReactionT.ValueChangedFcn = createCallbackFcn(app, @ReactionT_Callback, true);
            app.ReactionT.Tag = 'ReactionT';
            app.ReactionT.Value = '0';
            app.LFixHt = uieditfield(app.ParmPanel, 'text');
            app.LFixHt.ValueChangedFcn = createCallbackFcn(app, @LFixHt_Callback, true);
            app.LFixHt.Tag = 'LFixHt';
            app.LFixHt.Value = '0';
            app.LTargHt = uieditfield(app.ParmPanel, 'text');
            app.LTargHt.ValueChangedFcn = createCallbackFcn(app, @LTargHt_Callback, true);
            app.LTargHt.Tag = 'LTargHt';
            app.LTargHt.Value = '0';
            app.text23 = uilabel(app.ParmPanel);
            app.text23.Tag = 'text23';
            app.text23.Text = 'Wide';
            app.text24 = uilabel(app.ParmPanel);
            app.text24.Tag = 'text24';
            app.text24.Text = 'High';
            app.text25 = uilabel(app.ParmPanel);
            app.text25.Tag = 'text25';
            app.text25.Text = 'Random fix';
            app.e_RndFix = uieditfield(app.ParmPanel, 'text');
            app.e_RndFix.ValueChangedFcn = createCallbackFcn(app, @e_RndFix_Callback, true);
            app.e_RndFix.Tag = 'e_RndFix';
            app.e_RndFix.Value = '0';
            app.text26 = uilabel(app.ParmPanel);
            app.text26.Tag = 'text26';
            app.text26.Text = 'Random stim';
            app.e_RndStim = uieditfield(app.ParmPanel, 'text');
            app.e_RndStim.ValueChangedFcn = createCallbackFcn(app, @e_RndStim_Callback, true);
            app.e_RndStim.Tag = 'e_RndStim';
            app.e_RndStim.Value = '0';
            app.text27 = uilabel(app.ParmPanel);
            app.text27.Tag = 'text27';
            app.text27.Text = 'Add on error';
            app.e_Err = uieditfield(app.ParmPanel, 'text');
            app.e_Err.ValueChangedFcn = createCallbackFcn(app, @e_Err_Callback, true);
            app.e_Err.Tag = 'e_Err';
            app.e_Err.Value = '0';
            app.text28 = uilabel(app.ParmPanel);
            app.text28.Tag = 'text28';
            app.text28.Text = 'Saccade time';
            app.e_Sacc = uieditfield(app.ParmPanel, 'text');
            app.e_Sacc.ValueChangedFcn = createCallbackFcn(app, @e_Sacc_Callback, true);
            app.e_Sacc.Tag = 'e_Sacc';
            app.e_Sacc.Value = '0';
            app.TB_x = uibutton(app.ParmPanel, 'state');
            app.TB_x.ValueChangedFcn = createCallbackFcn(app, @TB_x_Callback, true);
            app.TB_x.Tag = 'TB_x';
            app.TB_x.Text = 'x*1';
            app.TB_y = uibutton(app.ParmPanel, 'state');
            app.TB_y.ValueChangedFcn = createCallbackFcn(app, @TB_y_Callback, true);
            app.TB_y.Tag = 'TB_y';
            app.TB_y.Text = 'y*1';
            app.text31 = uilabel(app.ParmPanel);
            app.text31.Tag = 'text31';
            app.text31.Text = 'Eye';
            app.text32 = uilabel(app.ParmPanel);
            app.text32.Tag = 'text32';
            app.text32.Text = 'Background color';
            app.E_RGB = uieditfield(app.ParmPanel, 'text');
            app.E_RGB.ValueChangedFcn = createCallbackFcn(app, @E_RGB_Callback, true);
            app.E_RGB.Tag = 'E_RGB';
            app.E_RGB.Tooltip = 'enter RGB value 0-1 0-1 0-1';
            app.E_RGB.Value = '0 0 0';
            app.text37 = uilabel(app.ParmPanel);
            app.text37.Tag = 'text37';
            app.text37.Text = 'Random targ';
            app.e_RndTarg = uieditfield(app.ParmPanel, 'text');
            app.e_RndTarg.ValueChangedFcn = createCallbackFcn(app, @e_RndTarg_Callback, true);
            app.e_RndTarg.Tag = 'e_RndTarg';
            app.e_RndTarg.Value = '0';
            app.T_RUN = uilabel(app.ParmPanel);
            app.T_RUN.Tag = 'T_RUN';
            app.T_RUN.Text = 'Run function ';
            app.RewHIB = uicheckbox(app.ParmPanel);
            app.RewHIB.ValueChangedFcn = createCallbackFcn(app, @RewHIB_Callback, true);
            app.RewHIB.Tag = 'RewHIB';
            app.RewHIB.Text = 'Reward requires hand in box';
            app.StimHIB = uicheckbox(app.ParmPanel);
            app.StimHIB.ValueChangedFcn = createCallbackFcn(app, @StimHIB_Callback, true);
            app.StimHIB.Tag = 'StimHIB';
            app.StimHIB.Text = 'Stimulus requires hand in box';
            app.FixHIB = uicheckbox(app.ParmPanel);
            app.FixHIB.ValueChangedFcn = createCallbackFcn(app, @FixHIB_Callback, true);
            app.FixHIB.Tag = 'FixHIB';
            app.FixHIB.Text = 'Fix dot requires hand in box';
            app.SecFixCol = uicheckbox(app.ParmPanel);
            app.SecFixCol.ValueChangedFcn = createCallbackFcn(app, @SecFixCol_Callback, true);
            app.SecFixCol.Tag = 'SecFixCol';
            app.SecFixCol.Text = 'Use second color when fixating';
            app.AutoDim = uicheckbox(app.ParmPanel);
            app.AutoDim.ValueChangedFcn = createCallbackFcn(app, @AutoDim_Callback, true);
            app.AutoDim.Tag = 'AutoDim';
            app.AutoDim.Text = 'Autodim when hand out box';
            app.AutoDimPerc = uieditfield(app.ParmPanel, 'text');
            app.AutoDimPerc.ValueChangedFcn = createCallbackFcn(app, @AutoDimPerc_Callback, true);
            app.AutoDimPerc.Tag = 'AutoDimPerc';
            app.AutoDimPerc.Value = '0';
            app.text15_2 = uilabel(app.ParmPanel);
            app.text15_2.Tag = 'text15';
            app.text15_2.Text = 'Target';
            app.text15_3 = uilabel(app.ParmPanel);
            app.text15_3.Tag = 'text15';
            app.text15_3.Text = 'Fix';
            app.squareButton = uibutton(app.ParmPanel, 'state');
            app.squareButton.ValueChangedFcn = createCallbackFcn(app, @FixWinSqr, true);
            app.squareButton.Text = 'square';
            app.DrummingonoffButton = uibutton(app.ParmPanel, 'state');
            app.DrummingonoffButton.ValueChangedFcn = createCallbackFcn(app, @Drum, true);
            app.DrummingonoffButton.Text = 'Drumming on/off';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tracker_CK_gui(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)tracker_CK_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end